classdef opto_analyser_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ImageIOLabel                matlab.ui.control.Label
        ReleasefileEditFieldLabel   matlab.ui.control.Label
        ReleasefileEditField        matlab.ui.control.EditField
        RecoveryfileEditFieldLabel  matlab.ui.control.Label
        RecoveryfileEditField       matlab.ui.control.EditField
        CellNameEditFieldLabel      matlab.ui.control.Label
        CellNameEditField           matlab.ui.control.EditField
        NoofintervalsLabel          matlab.ui.control.Label
        OKButton                    matlab.ui.control.StateButton
        ConfirmSelectionLabel       matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        OutputinformationLabel      matlab.ui.control.Label
        IntervalsselectedLabel      matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        ChannelselectionTab         matlab.ui.container.Tab
        Channel1Channel2Channel3Channel4Label  matlab.ui.control.Label
        FrameSliderLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        Channel1ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_5                matlab.ui.control.RadioButton
        MitochondriaButton          matlab.ui.control.RadioButton
        CytoplasmButton             matlab.ui.control.RadioButton
        NucleusButton               matlab.ui.control.RadioButton
        Channel2ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_6                matlab.ui.control.RadioButton
        MitochondriaButton_2        matlab.ui.control.RadioButton
        CytoplasmButton_2           matlab.ui.control.RadioButton
        NucleusButton_2             matlab.ui.control.RadioButton
        Channel3ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_7                matlab.ui.control.RadioButton
        MitochondriaButton_3        matlab.ui.control.RadioButton
        CytoplasmButton_3           matlab.ui.control.RadioButton
        AcceptChannelsandCropImageButton  matlab.ui.control.StateButton
        NucleusButton_3             matlab.ui.control.RadioButton
        Channel4ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_8                matlab.ui.control.RadioButton
        MitochondriaButton_4        matlab.ui.control.RadioButton
        CytoplasmButton_4           matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        AcceptCropButton            matlab.ui.control.StateButton
        NucleusButton_4             matlab.ui.control.RadioButton
        CellboundarycropTab         matlab.ui.container.Tab
        CellthresholdingTab         matlab.ui.container.Tab
        CompartmentthresholdingTab  matlab.ui.container.Tab
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        lsm_flag = 1 % flags whether files are both of lsm type (=1) or not (=0).
        error_counter = 0;
        intervals = [];
        
        LSMRel
        LSMRec
        TRel
        TRec
        channels
        u
        v
        interval_stage
        interval_counter
        
        nuclear_channel
        mitochondrial_channel
        protein_channel
       
        ImgCRelAll1
        ImgCRecAll1
        ImgCAll1
        IntensityAll1
        CellRel1
        
        vertstart
        vertend
        horzstart
        horzend
        
        app_stage=0 % Only allows app functionality to be implemented in the correct order
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OKButton
        function OKButtonValueChanged(app, event)
            value = app.OKButton.Value;
            app.lsm_flag = 1;
            app.error_counter = 0;
            app.OutputinformationLabel.Text = 'Output information';
            info_label = app.OutputinformationLabel.Text;
            release_file=app.ReleasefileEditField.Value;
            recovery_file=app.RecoveryfileEditField.Value;
            if (isempty(release_file)) || (isempty(release_file)) || (length(release_file) < 3) || (length(recovery_file) < 3)
               lsm_type_error = ['Error. Release and recovery' newline 'filename(s) cannot be empty.' newline 'There may be additional errors.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               return
            end
            if(strcmp(release_file(end-2:end),'lsm')==0)||(strcmp(recovery_file(end-2:end),'lsm')==0)
               lsm_type_error = ['Error. Image files must' newline 'be of lsm type.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
            end
            
            if (isfile(release_file) == 0) || (isfile(recovery_file) == 0)
                lsm_type_error = ['Error. At least one image' newline 'file does not exist.'];
                app.lsm_flag = 0;
                app.error_counter = app.error_counter +1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
            end
            if (isempty(app.CellNameEditField.Value))
               lsm_type_error = ['Error. Cell Name cannot be empty.' newline 'There may be additional errors.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               return
            end
            if (isempty(regexp(app.CellNameEditField.Value,'\W')) == 0)
                lsm_type_error = ['Error. Cell name cannot include' newline 'spaces or special characters.'];
                app.lsm_flag = 0;
                app.error_counter = app.error_counter +1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label; 
            end
           
           interval_string = app.TextArea.Value;
           interval_string = interval_string{1}; 
           if (isempty(interval_string)) 
               lsm_type_error = ['Error. No. of intervals cannot be empty.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               return
            end
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.intervals = [];
            print_intervals=1;
            value_split=strsplit(interval_string,',');
            for I=1:length(value_split)
                value_num=str2num(value_split{I});
                if(isempty(value_num))
                    lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    print_intervals=0;
                    break
                elseif (mod(value_num,1) ~= 0) ||  (value_num < 0)
                    lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    print_intervals=0;
                    break
                else
                    app.intervals = [app.intervals,value_num];    
                end
            end
            if(isempty(app.intervals) == 0) && (print_intervals == 1)
                integer_string = sprintf('%.0f,' , app.intervals);
                integer_string = integer_string(1:end-1);% strip final comma
                app.IntervalsselectedLabel.Text=[app.IntervalsselectedLabel.Text newline integer_string];
            end
            if (app.lsm_flag ==1)
                [app.LSMRel, varargout] = lsmread(app.ReleasefileEditField.Value);
                [app.TRel,app.channels,~,app.u,app.v]=size(app.LSMRel);
                
                [app.LSMRec, varargout] = lsmread(app.RecoveryfileEditField.Value);
                [app.TRec,~,~,u_rec,v_rec]=size(app.LSMRec);


%                 app.interval_stage=0;
%                 app.interval_counter=0;
                %[time, app.channel, dim, row, col] = size(app.LSMRel);
                
                if app.channels~=3 && app.channels~=4
                    lsm_type_error = ['Error. No. of channels must be 3 or 4.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                elseif u_rec ~= app.u || v_rec ~= app.v
                    lsm_type_error = ['Error. Spatial dimensions of' newline 'release and recovery phases' newline 'should be the same.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                else
                    ch1(:, :) = app.LSMRel(1, 1, 1, :, :);
                    ch2(:, :) = app.LSMRel(1, 2, 1, :, :);
                    ch3(:, :) = app.LSMRel(1, 3, 1, :, :);
                    
                    title(app.UIAxes, []);
                    xlabel(app.UIAxes, []);
                    ylabel(app.UIAxes, []);
                    app.UIAxes.XAxis.TickLabels = {};
                    app.UIAxes.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(ch1, 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], ...
                        'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                    
                    title(app.UIAxes2, []);
                    xlabel(app.UIAxes2, []);
                    ylabel(app.UIAxes2, []);
                    app.UIAxes2.XAxis.TickLabels = {};
                    app.UIAxes2.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(ch2, 'Parent', app.UIAxes2, ...
                        'XData', [1 app.UIAxes2.Position(3)], ...
                        'YData', [1 app.UIAxes2.Position(4)]);
                    % Set limits of axes
                    app.UIAxes2.XLim = [0 I.XData(2)];
                    app.UIAxes2.YLim = [0 I.YData(2)];
                    
                    title(app.UIAxes3, []);
                    xlabel(app.UIAxes3, []);
                    ylabel(app.UIAxes3, []);
                    app.UIAxes3.XAxis.TickLabels = {};
                    app.UIAxes3.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(ch3, 'Parent', app.UIAxes3, ...
                        'XData', [1 app.UIAxes3.Position(3)], ...
                        'YData', [1 app.UIAxes3.Position(4)]);
                    % Set limits of axes
                    app.UIAxes3.XLim = [0 I.XData(2)];
                    app.UIAxes3.YLim = [0 I.YData(2)];
                           
                    if app.channels == 4
                        ch4(:, :) = app.LSMRel(1, 4, 1, :, :);
                        title(app.UIAxes4, []);
                        xlabel(app.UIAxes4, []);
                        ylabel(app.UIAxes4, []);
                        app.UIAxes4.XAxis.TickLabels = {};
                        app.UIAxes4.YAxis.TickLabels = {};
                        % Display image and stretch to fill axes
                        I = imagesc(ch4, 'Parent', app.UIAxes4, ...
                            'XData', [1 app.UIAxes4.Position(3)], ...
                            'YData', [1 app.UIAxes4.Position(4)]);
                        % Set limits of axes
                        app.UIAxes4.XLim = [0 I.XData(2)];
                        app.UIAxes4.YLim = [0 I.YData(2)];
                    end
                    
                app.app_stage=1;
                
                end
                
            end
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = uint8(min(max(round(event.Value),1),app.TRel));
            if app.app_stage ==1
                ch1(:, :) = app.LSMRel(changingValue, 1, 1, :, :);
                ch2(:, :) = app.LSMRel(changingValue, 2, 1, :, :);
                ch3(:, :) = app.LSMRel(changingValue, 3, 1, :, :);
                    
                title(app.UIAxes, []);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imagesc(ch1, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                title(app.UIAxes2, []);
                xlabel(app.UIAxes2, []);
                ylabel(app.UIAxes2, []);
                app.UIAxes2.XAxis.TickLabels = {};
                app.UIAxes2.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imagesc(ch2, 'Parent', app.UIAxes2, ...
                    'XData', [1 app.UIAxes2.Position(3)], ...
                    'YData', [1 app.UIAxes2.Position(4)]);
                % Set limits of axes
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                
                title(app.UIAxes3, []);
                xlabel(app.UIAxes3, []);
                ylabel(app.UIAxes3, []);
                app.UIAxes3.XAxis.TickLabels = {};
                app.UIAxes3.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imagesc(ch3, 'Parent', app.UIAxes3, ...
                    'XData', [1 app.UIAxes3.Position(3)], ...
                    'YData', [1 app.UIAxes3.Position(4)]);
                % Set limits of axes
                app.UIAxes3.XLim = [0 I.XData(2)];
                app.UIAxes3.YLim = [0 I.YData(2)];
                       
                if app.channels == 4
                    ch4(:, :) = app.LSMRel(changingValue, 4, 1, :, :);
                    title(app.UIAxes4, []);
                    xlabel(app.UIAxes4, []);
                    ylabel(app.UIAxes4, []);
                    app.UIAxes4.XAxis.TickLabels = {};
                    app.UIAxes4.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(ch4, 'Parent', app.UIAxes4, ...
                        'XData', [1 app.UIAxes4.Position(3)], ...
                        'YData', [1 app.UIAxes4.Position(4)]);
                    % Set limits of axes
                    app.UIAxes4.XLim = [0 I.XData(2)];
                    app.UIAxes4.YLim = [0 I.YData(2)];
                end
                
            else
                info_label = 'Output information';
                lsm_type_error = ['Error. Complete I/O correctly.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                
           
            end
            
        end

        % Value changed function: AcceptChannelsandCropImageButton
        function AcceptChannelsandCropImageButtonValueChanged(app, event)
            value = app.AcceptChannelsandCropImageButton.Value;
            if app.app_stage ==1
                app.lsm_flag = 1;
                info_label = app.OutputinformationLabel.Text;
                if (app.NoneButton_8.Value == 0) && (app.channels == 3) 
                   lsm_type_error = ['Error. There are only three channels.' newline 'Channel 4 should be "None".'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                end
                
                total_nucleus_radio = app.NucleusButton.Value + app.NucleusButton_2.Value + app.NucleusButton_3.Value + app.NucleusButton_4.Value;
                total_mitochondria_radio = app.MitochondriaButton.Value + app.MitochondriaButton_2.Value + app.MitochondriaButton_3.Value + app.MitochondriaButton_4.Value;
                total_cytoplasm_radio = app.CytoplasmButton.Value + app.CytoplasmButton_2.Value + app.CytoplasmButton_3.Value + app.CytoplasmButton_4.Value;
                
                if total_cytoplasm_radio == 2
                   lsm_type_error = ['Error. The app can analyse only one protein' newline 'at a time at present'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
             
                end
                
                
                if total_nucleus_radio ~= 1 && total_mitochondria_radio ~= 1 && total_cytoplasm_radio ~= 1 && total_cytoplasm_radio ~= 2
                   lsm_type_error = ['Error. There must be one allocated' newline 'protein channel, nuclear channel and' newline 'mitochondrial channel.'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
             
                end
                
                if app.lsm_flag == 1
                    nucleus_location = [app.NucleusButton.Value, app.NucleusButton_2.Value, app.NucleusButton_3.Value, app.NucleusButton_4.Value];
                    protein_location = [app.CytoplasmButton.Value, app.CytoplasmButton_2.Value, app.CytoplasmButton_3.Value, app.CytoplasmButton_4.Value];
                    mitochondria_location = [app.MitochondriaButton.Value, app.MitochondriaButton_2.Value, app.MitochondriaButton_3.Value, app.MitochondriaButton_4.Value];
                    app.nuclear_channel = find(nucleus_location);
                    app.mitochondrial_channel = find(mitochondria_location);
                    app.protein_channel = find(protein_location);
                    
                    app.ImgCRelAll1=zeros(app.u,app.v,app.TRel);
                    for I = 1:app.TRel
                        app.ImgCRelAll1(:, :, I)=app.LSMRel(I, app.protein_channel, 1, :, :);
                    end
                    app.ImgCRecAll1=zeros(app.u,app.v,app.TRec);
                    for I = 1:app.TRec
                        app.ImgCRecAll1(:, :, I)=app.LSMRec(I, app.protein_channel, 1, :, :);
                    end
                    app.ImgCAll1 = cat(3, app.ImgCRelAll1, app.ImgCRecAll1);
                    app.IntensityAll1 = sum(sum(app.ImgCAll1,1), 2);
                    app.CellRel1(1:app.u, 1:app.v) = app.LSMRel(1,app.protein_channel,1,:,:);
    
                    crop_image = figure;
                    imagesc(app.CellRel1);
                    iptsetpref('ImshowAxesVisible','off')
                    %Draw a rectangle around image to get new image bounds
                    h = imrect;
                    BW = createMask(h);
                    delete(h);
                    delete(crop_image);
                    index = find(BW);
                    [y, x] = ind2sub(size(BW),index);
                    app.vertstart = min(y);
                    app.vertend = max(y);
                    app.horzstart = min(x);
                    app.horzend = max(x);
                    app.app_stage = 2;
                end
                
            else
                info_label = 'Output information';
                lsm_type_error = ['Error. Complete I/O correctly.'];
                app.error_counter = 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                
            end


        end

        % Value changed function: AcceptCropButton
        function AcceptCropButtonValueChanged(app, event)
            value = app.AcceptCropButton.Value;
             if app.app_stage ==2

                for I=1:app.TRel
                    app.ImgNRel(:,:,I) = app.LSMRel(I,app.nuclearchannel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    app.ImgCRel1(:,:,I) = app.LSMRel(I,app.proteinchannel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    app.ImgMRel(:,:,I) = app.LSMRel(I,app.mitochannel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                end;

                for I=1:app.TRec
                    app.ImgNRec(:,:,I) = app.LSMRec(I,app.nuclearchannel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    app.ImgCRec1(:,:,I) = app(I,app.proteinchannel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    app.ImgMRec(:,:,I) = app.LSMRec(I,app.mitochannel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                end;
                %Maximum of all release compartments (3d for time)
                app.AllCellRel = max(max(app.ImgNRel,app.ImgCRel1),app.ImgMRel);
                %Maximum of all recovery compartments (3d for time)
                app.AllCellRec = max(max(app.ImgNRec,app.ImgCRec1),app.ImgMRec);
                %2D maximum  of all release stages
                app.AllCellRelMax = max(app.AllCellRel,[],3);
                %2D maximum of all recovery stages
                app.AllCellRecMax = max(app.AllCellRec,[],3);
                %2D maximum of entire release and recovery movies
                app.AllCellMax = max(app.AllCellRelMax,app.AllCellRecMax);
                
                app.app_stage ==3;
                
                app.TabGroup.SelectedTab = app.CellboundarycropTab;

% %                 %Input matrices for 
% %                 %entire movie maximum over all channels, 
% %                 app.InputAllCellMax=app.AllCellMax;
% %                 %Cytoplasmic channel over entire release 
% %                 app.InputImgCRel=app.ImgCRel1;
% %                 %Entire cell max over release
% %                 app.AllCellRelInput=app.AllCellRel;
% %                 %Entire cell max over recovery
% %                 app.AllCellRecInput=app.AllCellRec;
                
%                 %Number of sections to split into
%                 handles.NoSections=Opto2DGUIhandles.NoSections;
%                 %handles.counter=1;
%                 %If there is more than one section
%                 if(handles.NoSections>1)%Can we get rid of this eventually?
%                    Length=Opto2DGUIhandles.TRec+Opto2DGUIhandles.TRel;%Total number of frames 
%                    handles.Regions=1:(Length-1)/handles.NoSections:Length;%Start point of each region.
%                    handles.Regions=round(handles.Regions);
%                    handles.Midpoints=(1+(Length-1)/(handles.NoSections))/2:(Length-1)/handles.NoSections:Length;
%                    handles.Midpoints=round(handles.Midpoints);
%                    handles.ImgN=cat(3,handles.ImgNRel,handles.ImgNRec);
%                    handles.ImgC1=cat(3,handles.ImgCRel1,handles.ImgCRec1);
%                    handles.ImgM=cat(3,handles.ImgMRel,handles.ImgMRec);
%                    handles.AllCellRegionMax=zeros([size(handles.InputAllCellMax),handles.NoSections]);
%                    handles.RegionNMax=zeros([size(handles.InputAllCellMax),handles.NoSections]);
%                    handles.RegionMMax=zeros([size(handles.InputAllCellMax),handles.NoSections]);
%                    handles.RegionC1Max=zeros([size(handles.InputAllCellMax),handles.NoSections]);
%                    handles.MedianRegion=zeros([size(handles.InputAllCellMax),handles.NoSections]);
%                    for J=1:handles.NoSections
%                        %Median intensity for maximum intensities over time
%                        handles.MedianRegion(:,:,J)=uint16(median(max(max(handles.ImgN(:,:,handles.Regions(J):handles.Regions(J+1)-1),handles.ImgC1(:,:,handles.Regions(J):handles.Regions(J+1)-1)),handles.ImgM(:,:,handles.Regions(J):handles.Regions(J+1)-1)),3));
%                        %Maximum intensity of nucleus in that region
%                        handles.RegionNMax(:,:,J)=max(handles.ImgN(:,:,handles.Regions(J):handles.Regions(J+1)-1),[],3);
%                        %Maximum intesnity of cytoplasm in that region
%                        handles.RegionC1Max(:,:,J)=max(handles.ImgC1(:,:,handles.Regions(J):handles.Regions(J+1)-1),[],3);
%                        %Maximum intensity of mitochondria in that region
%                        handles.RegionMMax(:,:,J)=max(handles.ImgM(:,:,handles.Regions(J):handles.Regions(J+1)-1),[],3);
%                        %Maximum intensity of entire cell over that time region
%                        handles.AllCellRegionMax(:,:,J)=max(handles.RegionMMax(:,:,J),max(handles.RegionNMax(:,:,J),handles.RegionC1Max(:,:,J)));
%                        handles.NucleusMedianRegions(:,:,J)=uint16(median(handles.ImgN(:,:,handles.Regions(J):handles.Regions(J+1)-1),3));
%                         %CHANGE BACK!!!%Test out 75th percentile, not median
%                        handles.NucleusMedianRegions(:,:,J)=uint16(prctile(handles.ImgN(:,:,handles.Regions(J):handles.Regions(J+1)-1),90,3));
%                        handles.MitoMedianRegions(:,:,J)=uint16(median(handles.ImgM(:,:,handles.Regions(J):handles.Regions(J+1)-1),3));
%                    end
%                    %Currently no else statement for only one section
%                    %Then as input files we need the 2d median intensity, the maximum
%                    %cytoplasmic intensity and the maximum nuclear intensity
%                    handles.MedianRegionInput=handles.MedianRegion;
%                    handles.InputImgCRegion=handles.RegionC1Max;
%                    handles.InputImgNRegion=handles.RegionNMax;
%                 end
%                 handles.stage=1;


             elseif app.app_stage ==1
                 info_label = 'Output information';
                 lsm_type_error = ['Error. Complete channel allocation and select' newline '"Accept Channels and Crop Image".'];
                 app.lsm_flag = 0;
                 app.error_counter = 1;
                 info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                 app.OutputinformationLabel.Text = info_label;
             elseif app.app_stage ==0
                 info_label = 'Output information';
                 lsm_type_error = ['Error. Complete I/O correctly.'];
                 app.lsm_flag = 0;
                 app.error_counter = 1;
                 info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                 app.OutputinformationLabel.Text = info_label;
             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageIOLabel = uilabel(app.LeftPanel);
            app.ImageIOLabel.Text = 'Image I/O';
            app.ReleasefileEditFieldLabel = uilabel(app.LeftPanel);
            app.ReleasefileEditFieldLabel.Text = 'Release file';
            app.ReleasefileEditField = uieditfield(app.LeftPanel, 'text');
            app.ReleasefileEditField.Value = 'C:\Documents\release.lsm';
            app.RecoveryfileEditFieldLabel = uilabel(app.LeftPanel);
            app.RecoveryfileEditFieldLabel.Text = 'Recovery file';
            app.RecoveryfileEditField = uieditfield(app.LeftPanel, 'text');
            app.RecoveryfileEditField.Value = 'C:\Documents\recovery.lsm';
            app.CellNameEditFieldLabel = uilabel(app.LeftPanel);
            app.CellNameEditFieldLabel.Text = 'Cell Name';
            app.CellNameEditField = uieditfield(app.LeftPanel, 'text');
            app.CellNameEditField.Value = 'cell_name';
            app.NoofintervalsLabel = uilabel(app.LeftPanel);
            app.NoofintervalsLabel.Text = 'No. of intervals';
            app.OKButton = uibutton(app.LeftPanel, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @OKButtonValueChanged, true);
            app.OKButton.Text = 'OK';
            app.ConfirmSelectionLabel = uilabel(app.LeftPanel);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.OutputinformationLabel = uilabel(app.LeftPanel);
            app.OutputinformationLabel.Text = 'Output information';
            app.TextArea = uitextarea(app.LeftPanel);
            app.IntervalsselectedLabel = uilabel(app.LeftPanel);
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.ChannelselectionTab = uitab(app.TabGroup);
            app.ChannelselectionTab.Title = 'Channel selection';
            app.Channel1Channel2Channel3Channel4Label = uilabel(app.ChannelselectionTab);
            app.Channel1Channel2Channel3Channel4Label.Text = '  Channel 1           Channel 2          Channel 3             Channel 4';
            app.FrameSliderLabel = uilabel(app.ChannelselectionTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ChannelselectionTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.Channel1ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.NoneButton_5 = uiradiobutton(app.Channel1ButtonGroup);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.MitochondriaButton = uiradiobutton(app.Channel1ButtonGroup);
            app.MitochondriaButton.Text = 'Mitochondria';
            app.CytoplasmButton = uiradiobutton(app.Channel1ButtonGroup);
            app.CytoplasmButton.Text = 'Cytoplasm';
            app.NucleusButton = uiradiobutton(app.Channel1ButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.Channel2ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.NoneButton_6 = uiradiobutton(app.Channel2ButtonGroup);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.MitochondriaButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.MitochondriaButton_2.Text = 'Mitochondria';
            app.CytoplasmButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.CytoplasmButton_2.Text = 'Cytoplasm';
            app.NucleusButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.NucleusButton_2.Text = 'Nucleus';
            app.Channel3ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.NoneButton_7 = uiradiobutton(app.Channel3ButtonGroup);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.MitochondriaButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.MitochondriaButton_3.Text = 'Mitochondria';
            app.CytoplasmButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.CytoplasmButton_3.Text = 'Cytoplasm';
            app.NucleusButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.NucleusButton_3.Text = 'Nucleus';
            app.Channel4ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel4ButtonGroup.Title = 'Channel 4';
            app.NoneButton_8 = uiradiobutton(app.Channel4ButtonGroup);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.MitochondriaButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.MitochondriaButton_4.Text = 'Mitochondria';
            app.CytoplasmButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.CytoplasmButton_4.Text = 'Cytoplasm';
            app.NucleusButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.NucleusButton_4.Text = 'Nucleus';
            app.AcceptChannelsandCropImageButton = uibutton(app.ChannelselectionTab, 'state');
            app.AcceptChannelsandCropImageButton.ValueChangedFcn = createCallbackFcn(app, @AcceptChannelsandCropImageButtonValueChanged, true);
            app.AcceptChannelsandCropImageButton.Text = 'Accept Channels and Crop Image';
            app.UIAxes = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.AcceptCropButton = uibutton(app.ChannelselectionTab, 'state');
            app.AcceptCropButton.ValueChangedFcn = createCallbackFcn(app, @AcceptCropButtonValueChanged, true);
            app.AcceptCropButton.Text = 'Accept Crop';
            app.CellboundarycropTab = uitab(app.TabGroup);
            app.CellboundarycropTab.Title = 'Cell boundary crop';
            app.CellthresholdingTab = uitab(app.TabGroup);
            app.CellthresholdingTab.Title = 'Cell thresholding';
            app.CompartmentthresholdingTab = uitab(app.TabGroup);
            app.CompartmentthresholdingTab.Title = 'Compartment thresholding';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opto_analyser_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end